classdef CWApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Reset                         matlab.ui.control.Button
        Save3Median                   matlab.ui.control.Button
        Save2Median                   matlab.ui.control.Button
        Save1Median                   matlab.ui.control.Button
        Save3Mean                     matlab.ui.control.Button
        Save2Mean                     matlab.ui.control.Button
        Save1Mean                     matlab.ui.control.Button
        RMSContrast3                  matlab.ui.control.Label
        RMSContrast2                  matlab.ui.control.Label
        RMSContrast1                  matlab.ui.control.Label
        Group10ProjectABloodImagesPanel  matlab.ui.container.Panel
        UniversityofWestminsterLabel  matlab.ui.control.Label
        AlexandraMarinandTreyaMaharajLabel  matlab.ui.control.Label
        ProcessLabel                  matlab.ui.control.Label
        ImageProcessingButton         matlab.ui.control.Button
        DisplayHistogramButton        matlab.ui.control.Button
        RGBtoGrayScaleButton          matlab.ui.control.Button
        LoadImagesButton              matlab.ui.control.Button
        PIMean3                       matlab.ui.control.UIAxes
        PIMean2                       matlab.ui.control.UIAxes
        PIMedian3                     matlab.ui.control.UIAxes
        PIMedian2                     matlab.ui.control.UIAxes
        Hist3                         matlab.ui.control.UIAxes
        Hist2                         matlab.ui.control.UIAxes
        GrayImg3                      matlab.ui.control.UIAxes
        GrayImg2                      matlab.ui.control.UIAxes
        Img3                          matlab.ui.control.UIAxes
        Img2                          matlab.ui.control.UIAxes
        PIMedian1                     matlab.ui.control.UIAxes
        PIMean1                       matlab.ui.control.UIAxes
        Hist1                         matlab.ui.control.UIAxes
        GrayImg1                      matlab.ui.control.UIAxes
        Img1                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImagesButton
        function loadImage(app, event)
            % Read the images
            bI1 = imread("BloodImg1.tif");
            bI2 = imread("BloodImg2.tif");
            bI3 = imread("BloodImg3.tif");

            % Display the images
            imshow(bI1, 'Parent', app.Img1); 
            imshow(bI2, 'Parent', app.Img2);
            imshow(bI3, 'Parent', app.Img3); 

            % Calculating the RMS Contrast for all 3 images
            contrast_value1 = calculateRMSContrast(bI1); % apply function to first image
            contrast_value2 = calculateRMSContrast(bI2); % apply function to second image
            contrast_value3 = calculateRMSContrast(bI3); % apply function to third image

            % Display the RMS Contrast
            app.RMSContrast1.Text = "RMS Contrast value: "+ contrast_value1;
            app.RMSContrast2.Text = "RMS Contrast value: "+ contrast_value2;
            app.RMSContrast3.Text = "RMS Contrast value: "+ contrast_value3;

            % Make RGB to gray button visible
            app.RGBtoGrayScaleButton.Visible = "on";
        end

        % Button pushed function: RGBtoGrayScaleButton
        function toGray(app, event)
            % Read the images
            bI1 = getimage(app.Img1);
            bI2 = getimage(app.Img2);
            bI3 = getimage(app.Img3);
            
            % Transform to Gray scale
            bJ1 = Grayscale(bI1);
            bJ2 = Grayscale(bI2);
            bJ3 = Grayscale(bI3);

            % Display the Gray scale images
            imshow(bJ1, 'Parent', app.GrayImg1);
            imshow(bJ2, 'Parent', app.GrayImg2);
            imshow(bJ3, 'Parent', app.GrayImg3);

            % Make Display Histogram button visible
            app.DisplayHistogramButton.Visible = "on";
        end

        % Button pushed function: DisplayHistogramButton
        function displayHistogram(app, event)
            % Transform to Gray scale
            bJ1 = getimage(app.GrayImg1);
            bJ2 = getimage(app.GrayImg2);
            bJ3 = getimage(app.GrayImg3);

            % Plot the histogram
            plotHistogram(bJ1, app.Hist1);
            plotHistogram(bJ2, app.Hist2);
            plotHistogram(bJ3, app.Hist3);

            % Make Image Processing button visible
            app.ImageProcessingButton.Visible = "on";
        end

        % Button pushed function: ImageProcessingButton
        function proccessedImage(app, event)
            % Transform to Gray scale
            bJ1 = getimage(app.GrayImg1);
            bJ2 = getimage(app.GrayImg2);
            bJ3 = getimage(app.GrayImg3);

            % Set the threshold
            threshold = 216;

            % Show all the images after being processed
            proccesssed_img1_1 = processImage(bJ1, threshold, 0);
            imshow(proccesssed_img1_1, 'Parent', app.PIMean1);
            proccesssed_img2_1 = processImage(bJ1, threshold, 1);
            imshow(proccesssed_img2_1, 'Parent', app.PIMedian1);

            proccesssed_img1_2 = processImage(bJ2, threshold, 0);
            imshow(proccesssed_img1_2, 'Parent', app.PIMean2);
            proccesssed_img2_2 = processImage(bJ2, threshold, 1);
            imshow(proccesssed_img2_2, 'Parent', app.PIMedian2);

            proccesssed_img1_3 = processImage(bJ3, threshold, 0);
            imshow(proccesssed_img1_3, 'Parent', app.PIMean3);
            proccesssed_img2_3 = processImage(bJ3, threshold, 1);
            imshow(proccesssed_img2_3, 'Parent', app.PIMedian3);

            % Make save buttons visible
            app.Save1Mean.Visible = "on";
            app.Save2Mean.Visible = "on";
            app.Save3Mean.Visible = "on";
            app.Save1Median.Visible = "on";
            app.Save2Median.Visible = "on";
            app.Save3Median.Visible = "on";
            app.Reset.Visible = "on";
        end

        % Button pushed function: Save1Mean
        function saveImg1Mean(app, event)
            img = getimage(app.PIMean1);
            imwrite(img, "image1mean.tif");
            uialert(app.UIFigure, "image1mean.tif succesfuly saved in the current folder", "Save"); 
        end

        % Button pushed function: Save2Mean
        function saveImg2Mean(app, event)
            img = getimage(app.PIMean2);
            imwrite(img, "image2mean.tif");
            uialert(app.UIFigure, "image2mean.tif succesfuly saved in the current folder", "Save");
        end

        % Button pushed function: Save3Mean
        function saveImg3Mean(app, event)
            img = getimage(app.PIMean3);
            imwrite(img, "image3mean.tif");
            uialert(app.UIFigure, "image3mean.tif succesfuly saved in the current folder", "Save");
        end

        % Button pushed function: Save1Median
        function saveImg1Median(app, event)
            img = getimage(app.PIMedian1);
            imwrite(img, "image1median.tif");
            uialert(app.UIFigure, "image1median.tif succesfuly saved in the current folder", "Save");
        end

        % Button pushed function: Save2Median
        function saveImg2Median(app, event)
            img = getimage(app.PIMedian2);
            imwrite(img, "image2median.tif");
            uialert(app.UIFigure, "image2median.tif succesfuly saved in the current folder", "Save");
        end

        % Button pushed function: Save3Median
        function saveImg3Median(app, event)
            img = getimage(app.PIMedian3);
            imwrite(img, "image3median.tif");
            uialert(app.UIFigure, "image3median.tif succesfuly saved in the current folder", "Save");
        end

        % Button pushed function: Reset
        function resetButton(app, event)
            % Reset the text
            app.RMSContrast1.Text = '';
            app.RMSContrast2.Text = '';
            app.RMSContrast3.Text = '';
          
            % Reset all the axes
            resetImg(app.Img1, [1.00,1.00,1.00]);
            resetImg(app.Img2, [1.00,1.00,1.00]);
            resetImg(app.Img3, [1.00,1.00,1.00]);
            
            resetImg(app.GrayImg1, [0.87,0.86,0.86]);
            resetImg(app.GrayImg2, [0.87,0.86,0.86]);
            resetImg(app.GrayImg3, [0.87,0.86,0.86]);
            
            resetHistogram(app.Hist1);
            resetHistogram(app.Hist2);
            resetHistogram(app.Hist3);
            
            resetImg(app.PIMean1, [0.88,0.81,0.90]);
            resetImg(app.PIMean2, [0.88,0.81,0.90]);
            resetImg(app.PIMean3, [0.88,0.81,0.90]);
            
            resetImg(app.PIMedian1, [0.88,0.81,0.90]);
            resetImg(app.PIMedian2, [0.88,0.81,0.90]);
            resetImg(app.PIMedian3, [0.88,0.81,0.90]);


            % Make save buttons invisible
            app.Save1Mean.Visible = "off";
            app.Save2Mean.Visible = "off";
            app.Save3Mean.Visible = "off";
            app.Save1Median.Visible = "off";
            app.Save2Median.Visible = "off";
            app.Save3Median.Visible = "off";
            app.RGBtoGrayScaleButton.Visible = "off";
            app.DisplayHistogramButton.Visible = "off";
            app.ImageProcessingButton.Visible = "off";
            app.Reset.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Img1 = uiaxes(app.UIFigure);
            title(app.Img1, 'Image 1')
            app.Img1.XTick = [];
            app.Img1.YTick = [];
            colormap(app.Img1, 'jet')
            app.GrayImg1 = uiaxes(app.UIFigure);
            title(app.GrayImg1, 'RGB to Gray Image1')
            app.GrayImg1.XTick = [];
            app.GrayImg1.YTick = [];
            app.Hist1 = uiaxes(app.UIFigure);
            title(app.Hist1, 'Histogram 1')
            app.Hist1.XTick = [];
            app.Hist1.YTick = [];
            app.PIMean1 = uiaxes(app.UIFigure);
            title(app.PIMean1, 'Processed with Mean Filter')
            app.PIMean1.XTick = [];
            app.PIMean1.YTick = [];
            app.PIMedian1 = uiaxes(app.UIFigure);
            title(app.PIMedian1, 'Processed with Median Filter')
            app.PIMedian1.XTick = [];
            app.PIMedian1.YTick = [];
            colormap(app.PIMedian1, 'cool')
            app.Img2 = uiaxes(app.UIFigure);
            title(app.Img2, 'Image 2')
            app.Img2.XTick = [];
            app.Img2.YTick = [];
            colormap(app.Img2, 'jet')
            app.Img3 = uiaxes(app.UIFigure);
            title(app.Img3, 'Image 3')
            app.Img3.XTick = [];
            app.Img3.YTick = [];
            colormap(app.Img3, 'jet')
            app.GrayImg2 = uiaxes(app.UIFigure);
            title(app.GrayImg2, 'RGB to Gray Image2')
            app.GrayImg2.XTick = [];
            app.GrayImg2.YTick = [];
            app.GrayImg3 = uiaxes(app.UIFigure);
            title(app.GrayImg3, 'RGB to Gray Image3')
            app.GrayImg3.XTick = [];
            app.GrayImg3.YTick = [];
            app.Hist2 = uiaxes(app.UIFigure);
            title(app.Hist2, 'Histogram 2')
            app.Hist2.XTick = [];
            app.Hist2.YTick = [];
            app.Hist3 = uiaxes(app.UIFigure);
            title(app.Hist3, 'Histogram 3')
            app.Hist3.XTick = [];
            app.Hist3.YTick = [];
            app.PIMedian2 = uiaxes(app.UIFigure);
            title(app.PIMedian2, 'Processed with Median Filter')
            app.PIMedian2.XTick = [];
            app.PIMedian2.YTick = [];
            colormap(app.PIMedian2, 'cool')
            app.PIMedian3 = uiaxes(app.UIFigure);
            title(app.PIMedian3, 'Processed with Median Filter')
            app.PIMedian3.XTick = [];
            app.PIMedian3.YTick = [];
            colormap(app.PIMedian3, 'cool')
            app.PIMean2 = uiaxes(app.UIFigure);
            title(app.PIMean2, 'Processed with Mean Filter')
            app.PIMean2.XTick = [];
            app.PIMean2.YTick = [];
            app.PIMean3 = uiaxes(app.UIFigure);
            title(app.PIMean3, 'Processed with Mean Filter')
            app.PIMean3.XTick = [];
            app.PIMean3.YTick = [];
            app.LoadImagesButton = uibutton(app.UIFigure, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @loadImage, true);
            app.LoadImagesButton.Text = '1. Load Images >>';
            app.RGBtoGrayScaleButton = uibutton(app.UIFigure, 'push');
            app.RGBtoGrayScaleButton.ButtonPushedFcn = createCallbackFcn(app, @toGray, true);
            app.RGBtoGrayScaleButton.Visible = 'off';
            app.RGBtoGrayScaleButton.Text = '2. RGB to Gray Scale >>';
            app.DisplayHistogramButton = uibutton(app.UIFigure, 'push');
            app.DisplayHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @displayHistogram, true);
            app.DisplayHistogramButton.Visible = 'off';
            app.DisplayHistogramButton.Text = '3. Display Histogram >>';
            app.ImageProcessingButton = uibutton(app.UIFigure, 'push');
            app.ImageProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @proccessedImage, true);
            app.ImageProcessingButton.Visible = 'off';
            app.ImageProcessingButton.Text = '4. Image Processing >>';
            app.Group10ProjectABloodImagesPanel = uipanel(app.UIFigure);
            app.Group10ProjectABloodImagesPanel.Title = 'Group 10, Project A - Blood Images';
            app.ProcessLabel = uilabel(app.Group10ProjectABloodImagesPanel);
            app.ProcessLabel.Text = 'Regionalization and Identification of Blood cells in Thin Blood images';
            app.AlexandraMarinandTreyaMaharajLabel = uilabel(app.Group10ProjectABloodImagesPanel);
            app.AlexandraMarinandTreyaMaharajLabel.Text = 'Alexandra Marin and Treya Maharaj';
            app.UniversityofWestminsterLabel = uilabel(app.Group10ProjectABloodImagesPanel);
            app.UniversityofWestminsterLabel.Text = 'University of Westminster';
            app.RMSContrast1 = uilabel(app.UIFigure);
            app.RMSContrast1.Text = '';
            app.RMSContrast2 = uilabel(app.UIFigure);
            app.RMSContrast2.Text = '';
            app.RMSContrast3 = uilabel(app.UIFigure);
            app.RMSContrast3.Text = '';
            app.Save1Mean = uibutton(app.UIFigure, 'push');
            app.Save1Mean.ButtonPushedFcn = createCallbackFcn(app, @saveImg1Mean, true);
            app.Save1Mean.Icon = fullfile(pathToMLAPP, 'Save.png');
            app.Save1Mean.Visible = 'off';
            app.Save1Mean.Text = '';
            app.Save2Mean = uibutton(app.UIFigure, 'push');
            app.Save2Mean.ButtonPushedFcn = createCallbackFcn(app, @saveImg2Mean, true);
            app.Save2Mean.Icon = fullfile(pathToMLAPP, 'Save.png');
            app.Save2Mean.Visible = 'off';
            app.Save2Mean.Text = '';
            app.Save3Mean = uibutton(app.UIFigure, 'push');
            app.Save3Mean.ButtonPushedFcn = createCallbackFcn(app, @saveImg3Mean, true);
            app.Save3Mean.Icon = fullfile(pathToMLAPP, 'Save.png');
            app.Save3Mean.Visible = 'off';
            app.Save3Mean.Text = '';
            app.Save1Median = uibutton(app.UIFigure, 'push');
            app.Save1Median.ButtonPushedFcn = createCallbackFcn(app, @saveImg1Median, true);
            app.Save1Median.Icon = fullfile(pathToMLAPP, 'Save.png');
            app.Save1Median.Visible = 'off';
            app.Save1Median.Text = '';
            app.Save2Median = uibutton(app.UIFigure, 'push');
            app.Save2Median.ButtonPushedFcn = createCallbackFcn(app, @saveImg2Median, true);
            app.Save2Median.Icon = fullfile(pathToMLAPP, 'Save.png');
            app.Save2Median.Visible = 'off';
            app.Save2Median.Text = '';
            app.Save3Median = uibutton(app.UIFigure, 'push');
            app.Save3Median.ButtonPushedFcn = createCallbackFcn(app, @saveImg3Median, true);
            app.Save3Median.Icon = fullfile(pathToMLAPP, 'Save.png');
            app.Save3Median.Visible = 'off';
            app.Save3Median.Text = '';
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @resetButton, true);
            app.Reset.Icon = fullfile(pathToMLAPP, 'Reset.jpg');
            app.Reset.Visible = 'off';
            app.Reset.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CWApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end